classdef SCORBOT_GUI_V0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PinzaRealEditField             matlab.ui.control.NumericEditField
        PinzaRealEditFieldLabel        matlab.ui.control.Label
        RotacionRealEditField          matlab.ui.control.NumericEditField
        RotacionRealEditFieldLabel     matlab.ui.control.Label
        InclinacionRealEditField       matlab.ui.control.NumericEditField
        InclinacionRealEditFieldLabel  matlab.ui.control.Label
        CodoRealEditField              matlab.ui.control.NumericEditField
        CodoRealEditFieldLabel         matlab.ui.control.Label
        HombroRealEditField            matlab.ui.control.NumericEditField
        HombroRealEditFieldLabel       matlab.ui.control.Label
        BaseRealEditField              matlab.ui.control.NumericEditField
        BaseRealEditFieldLabel         matlab.ui.control.Label
        Z_REALEditField                matlab.ui.control.NumericEditField
        Z_REALEditFieldLabel           matlab.ui.control.Label
        Y_REALEditField                matlab.ui.control.NumericEditField
        Y_REALEditFieldLabel           matlab.ui.control.Label
        X_REALEditField                matlab.ui.control.NumericEditField
        X_REALEditFieldLabel           matlab.ui.control.Label
        RESETButton                    matlab.ui.control.Button
        CINEMATICAINVERSAButton        matlab.ui.control.Button
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        CINEMATICADIRECTAButton        matlab.ui.control.Button
        PinzaSlider                    matlab.ui.control.Slider
        PinzaSliderLabel               matlab.ui.control.Label
        PinzaEditField                 matlab.ui.control.NumericEditField
        PinzaEditFieldLabel            matlab.ui.control.Label
        RotacionEditField              matlab.ui.control.NumericEditField
        RotacionEditFieldLabel         matlab.ui.control.Label
        InclinacionEditField           matlab.ui.control.NumericEditField
        InclinacionEditFieldLabel      matlab.ui.control.Label
        CodoEditField                  matlab.ui.control.NumericEditField
        CodoEditFieldLabel             matlab.ui.control.Label
        HombroEditField                matlab.ui.control.NumericEditField
        HombroEditFieldLabel           matlab.ui.control.Label
        BaseEditField                  matlab.ui.control.NumericEditField
        BaseEditFieldLabel             matlab.ui.control.Label
        PuertoSerieLamp                matlab.ui.control.Lamp
        PuertoSerieLampLabel           matlab.ui.control.Label
        INICIARButton                  matlab.ui.control.Button
        ENVIARButton                   matlab.ui.control.Button
        RotacionKnob                   matlab.ui.control.Knob
        RotacionKnobLabel              matlab.ui.control.Label
        InclinacionKnob                matlab.ui.control.Knob
        InclinacionKnobLabel           matlab.ui.control.Label
        CodoKnob                       matlab.ui.control.Knob
        CodoKnobLabel                  matlab.ui.control.Label
        HombroKnob                     matlab.ui.control.Knob
        HombroKnobLabel                matlab.ui.control.Label
        BaseKnob                       matlab.ui.control.Knob
        BaseKnobLabel                  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2                          matlab.ui.container.Menu
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        serialObj % Objeto para comunicación serie
    end
    
    methods (Access = private)
        
        function T = cinematicaDirectaSTL(app, q)
    % === Cinemática directa y visualización 3D con STL ===
    % q: vector de ángulos articulares [q1 q2 q3 q4 q5] en grados
    % Devuelve la matriz homogénea final T

    % --- Paso 1: Pasar q a radianes ---
          q = q * pi/180;

    % --- Paso 2: Parámetros DH ---
          teta = [q(1) q(2)+pi/2+pi/6 -q(2)+q(3)-pi/2-pi/6 q(4)-q(3) q(5)];
          d = [35 0 0 0 14];
          alfa = [pi/2 0 0 pi/2 0];
          a = [1.5 22.2 22.2 0 0];

    % --- Paso 3: Matrices homogéneas individuales ---
          A01 = denavit(app, teta(1),d(1),a(1),alfa(1));
          A12 = denavit(app, teta(2),d(2),a(2),alfa(2));
          A23 = denavit(app, teta(3),d(3),a(3),alfa(3));
          A34 = denavit(app, teta(4),d(4),a(4),alfa(4));
          A45 = denavit(app, teta(5),d(5),a(5),alfa(5));

    % --- Paso 4: Acumuladas ---
          A02 = A01*A12;
          A03 = A02*A23;
          A04 = A03*A34;
          A05 = A04*A45;
          T = A05;
    % Vector de posicion

    POS_ABS = [T(1,4), T(2,4), T(3,4)];
    
    % Esta matriz es para rotar el STL LINK4
    R_q5 = [ cos(q(5)) -sin(q(5)) 0 0;
         sin(q(5))  cos(q(5)) 0 0;
               0         0  1 0;
               0         0  0 1];
    % --- Paso 5: Puntos de articulaciones (para referencia visual) ---
    P = [ 0 0 0;
          A01(1:3,4)';
          A02(1:3,4)';
          A03(1:3,4)';
          A04(1:3,4)';
          A05(1:3,4)' ];

    % --- Paso 6: Configuración del gráfico ---
          axes(app.UIAxes); % Asegura que todo se dibuje aquí
          cla(app.UIAxes); % Limpiar axes antes de dibujar
          hold(app.UIAxes, 'on'); grid(app.UIAxes,'on'); axis(app.UIAxes,'equal');
          xlabel(app.UIAxes, 'X'); ylabel(app.UIAxes, 'Y'); zlabel(app.UIAxes, 'Z');
          axis(app.UIAxes, [-70 70 -70 70 0 100]);
          view(app.UIAxes, 135,30);
          title(app.UIAxes, 'Brazo robótico con STL y cinemática directa');

    % --- Paso 7: Dibujar STLs de cada parte ---
          try
            % Base fija
            dibujarSTL(app,'BASE.stl', eye(4), [0.8 0.8 0.8]);

            % Eslabones (colores distintos)
            dibujarSTL(app,'LINK1.stl', A01, [0.9 0.5 0.3]); %Giro de la base
            dibujarSTL(app,'LINK2.stl', A02, [0.3 0.8 0.5]); %Giro del hombro
            dibujarSTL(app,'LINK3.stl', A03, [0.4 0.6 0.9]); %Giro del codo
            dibujarSTL(app,'LINK4.stl', A04*R_q5, [0.7 0.7 0.7]); %Inclinación MÁS Rotación de la muñeca

                 camlight(app.UIAxes,'headlight');
                % material dull;
          catch ME
                warning(ME.identifier, 'Error al dibujar STLs: %s', ME.message);
          end

          % --- Paso 8: Esqueleto (opcional, para referencia) ---
          plot3(app.UIAxes, P(:,1), P(:,2), P(:,3), 'k.-', 'LineWidth', 2, 'MarkerSize', 15);

          % Escribir la posición en los campos X Y Z
          
          app.XEditField.Value = POS_ABS(1);
          app.YEditField.Value = POS_ABS(2);          
          app.ZEditField.Value = POS_ABS(3);

        end
% -----------------------------------------------------------------
% -----------------------------------------------------------------
        function q = cinematicaInversa(app, p)
        % ... código de cinematicaInversa
            
            x = p(1);
            y = p(2);
            z = p(3);
            
            % Se pasan los angulos de grados a rad
            q4 = p(4)*pi/180;
            
            % Parametros DH
            d = [35 0 0 0 14];
            %alfa = [pi/2 0 0 pi/2 0]; %Alfa está comentado porque no se
            %utiliza en la función de cinemática inversa
            a = [1.5 22.2 22.2 0 0];
            
            % Cálculo de R, z13 y r
            R = sqrt(x^2+y^2)-d(5)*sin(q4)-a(1);
            z13 = z + d(5)*cos(q4)-d(1);
            r = sqrt(R^2+z13^2);
            
            % Cálculo de q1
            q(1)=atan2(y, x);
            
            % Cálculo de q2
            cbeta = (r^2+a(2)^2-a(3)^2)/(2*r*a(2));
            sbeta = sqrt(1-cbeta^2);
            calfa = (z13)/r;
            salfa = R/r;
            beta = atan2(sbeta,cbeta);
            alfa = atan2(salfa,calfa);
            q(2) = alfa - beta + pi/6;
            
            % Cálculo de q3
            cq3 = (R-a(2)*sin(q(2)-pi/6))/a(3);
            sq3 = (z13-a(3)*cos(q(2)-pi/6))/a(3);
            q(3) = atan2(sq3,cq3);
            
            %Tengo que cambiar q3 de signo
            
            q(2) = -q(2);
            
            % Se pasan los ángulos de radines a grados
            q = q*180/pi;
            
            % q4 y q5 coinciden con p(4) y p(5)
            q(4) = p(4);
            q(5) = p(5);

            app.BaseEditField.Value        = q(1);
            app.HombroEditField.Value      = q(2);
            app.CodoEditField.Value        = q(3);
            app.InclinacionEditField.Value = q(4);
            app.RotacionEditField.Value    = q(5);
            drawnow;

        end
% -----------------------------------------------------------------
% -----------------------------------------------------------------
        function dh = denavit(~, teta, d, a, alfa)
            dh = [cos(teta), -cos(alfa)*sin(teta),  sin(alfa)*sin(teta), a*cos(teta);
                  sin(teta),  cos(alfa)*cos(teta), -sin(alfa)*cos(teta), a*sin(teta);
                  0,          sin(alfa),            cos(alfa),           d;
                  0,          0,                    0,                   1];
        end
% -----------------------------------------------------------------
% -----------------------------------------------------------------    
        function dibujarSTL(app, nombreSTL, A, color)
            tri = stlread(nombreSTL);
            if isa(tri, 'triangulation')
                fv.faces = tri.ConnectivityList;
                fv.vertices = tri.Points;
            else
                fv = tri;
            end
            fv.vertices = fv.vertices * 0.1; % Convertir mm a cm
            n = size(fv.vertices,1);
            homog = [fv.vertices, ones(n,1)] * A';
            fv.vertices = homog(:,1:3);
            patch(app.UIAxes, 'Faces', fv.faces, 'Vertices', fv.vertices, ...
                'FaceColor', color, 'EdgeColor', 'none', 'FaceLighting', 'gouraud', ...
                'AmbientStrength', 0.15);

        end
% -----------------------------------------------------------------
% -----------------------------------------------------------------         
        function reset_q(app)
            app.BaseEditField.Value = 0;
            app.HombroEditField.Value = 0;
            app.CodoEditField.Value = 0;
            app.InclinacionEditField.Value = 0;
            app.CodoEditField.Value = 0;
            app.PinzaEditField.Value = 0;
        end
% -----------------------------------------------------------------
% -----------------------------------------------------------------         
        function valores = leerPosiciones(app)
            % Esperar a recibir el byte de inicio
            while true
                if app.serialObj.NumBytesAvailable > 0
                    header = read(app.serialObj, 1, "uint8");
                    if header == 170 % 0xAA
                        break;
                    end
                end
            end
        
            % Leer los 6 valores int16 (12 bytes)
            while app.serialObj.NumBytesAvailable < 12
                pause(0.01); % esperar a que lleguen todos los bytes
            end
            % Ahora 'valores' es un vector [base hombro codo inclinación rotación pinza]
            valores = read(app.serialObj, 6, "int16");
        end
% -----------------------------------------------------------------
% -----------------------------------------------------------------     
        function T_real = cinematicaDirectaReal(app, q_real)
          q_real = q_real * pi/180;
          teta = [q_real(1) q_real(2)+pi/2+pi/6 -q_real(2)+q_real(3)-pi/2-pi/6 q_real(4)-q_real(3) q_real(5)];
          d = [35 0 0 0 14];
          alfa = [pi/2 0 0 pi/2 0];
          a = [1.5 22.2 22.2 0 0];

          A01 = denavit(app, teta(1),d(1),a(1),alfa(1));
          A12 = denavit(app, teta(2),d(2),a(2),alfa(2));
          A23 = denavit(app, teta(3),d(3),a(3),alfa(3));
          A34 = denavit(app, teta(4),d(4),a(4),alfa(4));
          A45 = denavit(app, teta(5),d(5),a(5),alfa(5));

          A02 = A01*A12;
          A03 = A02*A23;
          A04 = A03*A34;
          A05 = A04*A45;
          T_real = A05;

          POS_REAL = [T_real(1,4), T_real(2,4), T_real(3,4)];

          app.X_REALEditField.Value = POS_REAL(1);
          app.Y_REALEditField.Value = POS_REAL(2);          
          app.Z_REALEditField.Value = POS_REAL(3);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    % Configura UIAxes
    axes(app.UIAxes);
    cla(app.UIAxes);           % Limpia cualquier contenido previo
    hold(app.UIAxes, 'on');
    grid(app.UIAxes, 'on');
    axis(app.UIAxes, 'equal');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z');
    title(app.UIAxes, 'Brazo robótico con STL y cinemática directa');
    axis(app.UIAxes, [-70 70 -70 70 0 100]);
    view(app.UIAxes, 135, 30);

    % Dibuja robot en posición inicial para que no haya cambio brusco
    try
        q0 = [0 0 0 0 0];        % posición inicial de los joints
        cinematicaDirectaSTL(app, q0);
    catch ME
        warning(ME.identifier, 'Error al dibujar STLs: %s', ME.message);
    end
    % === Deshabilitar todos los controles excepto Iniciar ===
componentes = [ ...
    app.ENVIARButton, app.RESETButton, ...
    app.CINEMATICADIRECTAButton, app.CINEMATICAINVERSAButton, ...
    app.BaseKnob, app.HombroKnob, app.CodoKnob, ...
    app.InclinacionKnob, app.RotacionKnob, ...
    app.PinzaSlider, ...
    app.BaseEditField, app.HombroEditField, app.CodoEditField, ...
    app.InclinacionEditField, app.RotacionEditField, app.PinzaEditField, ...
    app.XEditField, app.YEditField, app.ZEditField, ...
    app.X_REALEditField, app.Y_REALEditField, app.Z_REALEditField, ...
    app.BaseRealEditField, app.HombroRealEditField, app.CodoRealEditField, ...
    app.InclinacionRealEditField, app.RotacionRealEditField, app.PinzaRealEditField ...
];

for c = componentes
    c.Enable = 'off';
end

app.X_REALEditField.Editable = 'off';
app.Y_REALEditField.Editable = 'off';
app.Z_REALEditField.Editable = 'off';
app.BaseRealEditField.Editable = 'off';
app.HombroRealEditField.Editable = 'off';
app.CodoRealEditField.Editable = 'off';
app.InclinacionRealEditField.Editable = 'off';
app.RotacionRealEditField.Editable = 'off';
app.PinzaRealEditField.Editable = 'off';
        end

        % Value changing function: HombroKnob
        function HombroKnobValueChanging(app, event)
            changingValue = event.Value;
            app.HombroEditField.Value = changingValue;
        end

        % Value changing function: BaseKnob
        function BaseKnobValueChanging(app, event)
            changingValue = event.Value;
            app.BaseEditField.Value = changingValue;
        end

        % Value changing function: CodoKnob
        function CodoKnobValueChanging(app, event)
            changingValue = event.Value;
            app.CodoEditField.Value = changingValue;
        end

        % Value changing function: InclinacionKnob
        function InclinacionKnobValueChanging(app, event)
            changingValue = event.Value;
            app.InclinacionEditField.Value = changingValue;
        end

        % Value changing function: RotacionKnob
        function RotacionKnobValueChanging(app, event)
            changingValue = event.Value;
            app.RotacionEditField.Value = changingValue;
        end

        % Value changing function: PinzaSlider
        function PinzaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.PinzaEditField.Value = changingValue;
        end

        % Button pushed function: INICIARButton
        function INICIARButtonPushed(app, event)
    try
        delete(serialportfind);
        puertos = serialportlist("available");
        
        if isempty(puertos)
            uialert(app.UIFigure, 'No se detectaron puertos serie disponibles.', 'Error');
            return;
        end
        
        app.serialObj = serialport("COM4", 115200);
        configureTerminator(app.serialObj, "CR/LF");
        flush(app.serialObj);
        
        % Si se conecta correctamente
        app.PuertoSerieLamp.Color = [0.00,1.00,0.00];
        msgbox('Puerto serie iniciado correctamente.', 'Conexión Exitosa', 'help');
        
        % === Habilitar los controles ===
        componentes = [ ...
            app.ENVIARButton, app.HOMEButton, app.RESETButton, ...
            app.CINEMATICADIRECTAButton, app.CINEMATICAINVERSAButton, ...
            app.BaseKnob, app.HombroKnob, app.CodoKnob, ...
            app.InclinacionKnob, app.RotacionKnob, ...
            app.PinzaSlider, ...
            app.BaseEditField, app.HombroEditField, app.CodoEditField, ...
            app.InclinacionEditField, app.RotacionEditField, app.PinzaEditField, ...
            app.XEditField, app.YEditField, app.ZEditField ...
            app.X_REALEditField, app.Y_REALEditField, app.Z_REALEditField, ...
            app.BaseRealEditField, app.HombroRealEditField, app.CodoRealEditField, ...
            app.InclinacionRealEditField, app.RotacionRealEditField, app.PinzaRealEditField ...
          ];

        for c = componentes
            c.Enable = 'on';
        end

    catch ME
        app.PuertoSerieLamp.Color = [1 0 0];
        uialert(app.UIFigure, sprintf('Error al iniciar el puerto serie:\n%s', ME.message), 'Error');
    end
        end

        % Button pushed function: CINEMATICADIRECTAButton
        function CINEMATICADIRECTAButtonPushed(app, event)
            q = [0 0 0 0 0];
            q(1) = app.BaseEditField.Value;
            q(2) = app.HombroEditField.Value;
            q(3) = app.CodoEditField.Value;
            q(4) = app.InclinacionEditField.Value;
            q(5) = app.RotacionEditField.Value;

            cinematicaDirectaSTL(app, q)
            %write(app.serialObj,app.BaseEditField.Value,"int16");

        end

        % Button pushed function: CINEMATICAINVERSAButton
        function CINEMATICAINVERSAButtonPushed(app, event)
            p = [0 0 0 app.InclinacionEditField.Value app.RotacionEditField.Value];
            p(1) = app.XEditField.Value;
            p(2) = app.YEditField.Value;
            p(3) = app.ZEditField.Value;
            cinematicaInversa(app, p)
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            reset_q(app)
            q = [0 0 0 0 0];
            cinematicaDirectaSTL(app,q)
        end

        % Button pushed function: ENVIARButton
        function ENVIARButtonPushed(app, event)
    write(app.serialObj, app.BaseEditField.Value, "int16");
    pause(0.15)

    write(app.serialObj, app.HombroEditField.Value, "int16");
    pause(0.15)

    write(app.serialObj, app.CodoEditField.Value, "int16");
    pause(0.15)

    write(app.serialObj, app.InclinacionEditField.Value, "int16");
    pause(0.15)

    write(app.serialObj, app.RotacionEditField.Value, "int16");
    pause(0.15)

    write(app.serialObj, app.PinzaEditField.Value, "int16");
    pause(0.15)

    pos = leerPosiciones(app);

    app.BaseRealEditField.Value = pos(1);
    app.HombroRealEditField.Value = pos(2);
    app.CodoRealEditField.Value = pos(3);
    app.InclinacionRealEditField.Value = pos(4);
    app.RotacionRealEditField.Value = pos(5);
    app.PinzaRealEditField.Value = pos(6);

    cinematicaDirectaReal(app, pos);
        end

        % Callback function: not associated with a component
        function AGREGARPOSICIONButtonPushed(app, event)

    data = app.UITable.Data;

    % Si la tabla está vacía, empezamos en la fila 1
    if isempty(data)
        mov = 1;
        data(1,1:7) = NaN; % crea la primera fila
    else
        % Buscar la primera fila vacía
        mov = 1;
        while mov <= size(data,1) && ~isnan(data(mov,1))
            mov = mov + 1;
        end

        % Si llegamos al final, crear una nueva fila
        if mov > size(data,1)
            data(mov,1:7) = NaN;
        end
    end

    % Guardar valores
    data(mov,1) = mov;
    data(mov,2) = app.BaseEditField.Value;
    data(mov,3) = app.HombroEditField.Value;
    data(mov,4) = app.CodoEditField.Value;
    data(mov,5) = app.InclinacionEditField.Value;
    data(mov,6) = app.RotacionEditField.Value;
    data(mov,7) = app.PinzaSlider.Value;

    % Actualizar la tabla
    app.UITable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SCORBOT ER III')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BaseKnobLabel = uilabel(app.UIFigure);
            app.BaseKnobLabel.Text = 'Base';
            app.BaseKnob = uiknob(app.UIFigure, 'continuous');
            app.BaseKnob.Limits = [-115 115];
            app.BaseKnob.ValueChangingFcn = createCallbackFcn(app, @BaseKnobValueChanging, true);
            app.HombroKnobLabel = uilabel(app.UIFigure);
            app.HombroKnobLabel.Text = 'Hombro';
            app.HombroKnob = uiknob(app.UIFigure, 'continuous');
            app.HombroKnob.Limits = [-120 0];
            app.HombroKnob.ValueChangingFcn = createCallbackFcn(app, @HombroKnobValueChanging, true);
            app.CodoKnobLabel = uilabel(app.UIFigure);
            app.CodoKnobLabel.Text = 'Codo';
            app.CodoKnob = uiknob(app.UIFigure, 'continuous');
            app.CodoKnob.ValueChangingFcn = createCallbackFcn(app, @CodoKnobValueChanging, true);
            app.InclinacionKnobLabel = uilabel(app.UIFigure);
            app.InclinacionKnobLabel.Text = 'Inclinacion';
            app.InclinacionKnob = uiknob(app.UIFigure, 'continuous');
            app.InclinacionKnob.ValueChangingFcn = createCallbackFcn(app, @InclinacionKnobValueChanging, true);
            app.RotacionKnobLabel = uilabel(app.UIFigure);
            app.RotacionKnobLabel.Text = 'Rotacion';
            app.RotacionKnob = uiknob(app.UIFigure, 'continuous');
            app.RotacionKnob.ValueChangingFcn = createCallbackFcn(app, @RotacionKnobValueChanging, true);
            app.ENVIARButton = uibutton(app.UIFigure, 'push');
            app.ENVIARButton.ButtonPushedFcn = createCallbackFcn(app, @ENVIARButtonPushed, true);
            app.ENVIARButton.Text = 'ENVIAR';
            app.INICIARButton = uibutton(app.UIFigure, 'push');
            app.INICIARButton.ButtonPushedFcn = createCallbackFcn(app, @INICIARButtonPushed, true);
            app.INICIARButton.Text = 'INICIAR';
            app.PuertoSerieLampLabel = uilabel(app.UIFigure);
            app.PuertoSerieLampLabel.Text = 'Puerto Serie';
            app.PuertoSerieLamp = uilamp(app.UIFigure);
            app.BaseEditFieldLabel = uilabel(app.UIFigure);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.HombroEditFieldLabel = uilabel(app.UIFigure);
            app.HombroEditFieldLabel.Text = 'Hombro';
            app.HombroEditField = uieditfield(app.UIFigure, 'numeric');
            app.CodoEditFieldLabel = uilabel(app.UIFigure);
            app.CodoEditFieldLabel.Text = 'Codo';
            app.CodoEditField = uieditfield(app.UIFigure, 'numeric');
            app.InclinacionEditFieldLabel = uilabel(app.UIFigure);
            app.InclinacionEditFieldLabel.Text = 'Inclinacion';
            app.InclinacionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotacionEditFieldLabel = uilabel(app.UIFigure);
            app.RotacionEditFieldLabel.Text = 'Rotacion';
            app.RotacionEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaEditFieldLabel = uilabel(app.UIFigure);
            app.PinzaEditFieldLabel.Text = 'Pinza';
            app.PinzaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaSliderLabel = uilabel(app.UIFigure);
            app.PinzaSliderLabel.Text = 'Pinza';
            app.PinzaSlider = uislider(app.UIFigure);
            app.PinzaSlider.Limits = [-190 190];
            app.PinzaSlider.ValueChangingFcn = createCallbackFcn(app, @PinzaSliderValueChanging, true);
            app.CINEMATICADIRECTAButton = uibutton(app.UIFigure, 'push');
            app.CINEMATICADIRECTAButton.ButtonPushedFcn = createCallbackFcn(app, @CINEMATICADIRECTAButtonPushed, true);
            app.CINEMATICADIRECTAButton.Text = 'CINEMATICA DIRECTA';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.CINEMATICAINVERSAButton = uibutton(app.UIFigure, 'push');
            app.CINEMATICAINVERSAButton.ButtonPushedFcn = createCallbackFcn(app, @CINEMATICAINVERSAButtonPushed, true);
            app.CINEMATICAINVERSAButton.Text = 'CINEMATICA INVERSA';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.X_REALEditFieldLabel = uilabel(app.UIFigure);
            app.X_REALEditFieldLabel.Text = 'X_REAL';
            app.X_REALEditField = uieditfield(app.UIFigure, 'numeric');
            app.Y_REALEditFieldLabel = uilabel(app.UIFigure);
            app.Y_REALEditFieldLabel.Text = 'Y_REAL';
            app.Y_REALEditField = uieditfield(app.UIFigure, 'numeric');
            app.Z_REALEditFieldLabel = uilabel(app.UIFigure);
            app.Z_REALEditFieldLabel.Text = 'Z_REAL';
            app.Z_REALEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseRealEditFieldLabel = uilabel(app.UIFigure);
            app.BaseRealEditFieldLabel.Text = 'Base Real';
            app.BaseRealEditField = uieditfield(app.UIFigure, 'numeric');
            app.HombroRealEditFieldLabel = uilabel(app.UIFigure);
            app.HombroRealEditFieldLabel.Text = 'Hombro Real';
            app.HombroRealEditField = uieditfield(app.UIFigure, 'numeric');
            app.CodoRealEditFieldLabel = uilabel(app.UIFigure);
            app.CodoRealEditFieldLabel.Text = 'Codo Real';
            app.CodoRealEditField = uieditfield(app.UIFigure, 'numeric');
            app.InclinacionRealEditFieldLabel = uilabel(app.UIFigure);
            app.InclinacionRealEditFieldLabel.Text = 'Inclinacion Real';
            app.InclinacionRealEditField = uieditfield(app.UIFigure, 'numeric');
            app.RotacionRealEditFieldLabel = uilabel(app.UIFigure);
            app.RotacionRealEditFieldLabel.Text = 'Rotacion Real';
            app.RotacionRealEditField = uieditfield(app.UIFigure, 'numeric');
            app.PinzaRealEditFieldLabel = uilabel(app.UIFigure);
            app.PinzaRealEditFieldLabel.Text = 'Pinza Real';
            app.PinzaRealEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCORBOT_GUI_V0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end